classdef UniformRandomDistribution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label                       matlab.ui.control.Label
        Group13ProjectLabel         matlab.ui.control.Label
        SeedEditField               matlab.ui.control.NumericEditField
        SeedEditFieldLabel          matlab.ui.control.Label
        EnableSeedCheckBox          matlab.ui.control.CheckBox
        GenerateNumberButton        matlab.ui.control.Button
        RandomNumberEditField       matlab.ui.control.NumericEditField
        RandomNumberEditFieldLabel  matlab.ui.control.Label
        RandomNumberGeneratorLabel  matlab.ui.control.Label
        MaximumValueEditField       matlab.ui.control.NumericEditField
        MaximumValueEditFieldLabel  matlab.ui.control.Label
        MinimumValueEditField       matlab.ui.control.NumericEditField
        MinimumValueEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateNumberButton
        function GenerateNumberButtonPushed(app, event)
                seed = app.SeedEditField.Value;
                value = app.EnableSeedCheckBox.Value;
                minVal = app.MinimumValueEditField.Value;
                maxVal = app.MaximumValueEditField.Value;
                if minVal > maxVal
                    app.Label.Text = 'Minimum Value Exceeds the Maximum Value.';
                else
                    app.Label.Text = '';

                    if value == 0
                        result = randi([minVal,maxVal]);
                    else
                        rng(seed)
                        result = randi([minVal,maxVal]);
                    end
                    app.RandomNumberEditField.Value = result;
                end
        end

        % Value changed function: EnableSeedCheckBox
        function EnableSeedCheckBoxValueChanged(app, event)
            value = app.EnableSeedCheckBox.Value;
            if value == 1
                app.SeedEditField.Visible = 'on';
                app.SeedEditFieldLabel.Visible = 'on';
            else
                app.SeedEditField.Visible = 'off';
                app.SeedEditFieldLabel.Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MinimumValueEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumValueEditFieldLabel.Text = 'Minimum Value';
            app.MinimumValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumValueEditField.Tooltip = {'Give a maximim value to generate a number'};
            app.MaximumValueEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumValueEditFieldLabel.Text = 'Maximum Value';
            app.MaximumValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumValueEditField.Tooltip = {'Give a minimum value to generate a number'};
            app.RandomNumberGeneratorLabel = uilabel(app.UIFigure);
            app.RandomNumberGeneratorLabel.Text = 'Random Number Generator';
            app.RandomNumberEditFieldLabel = uilabel(app.UIFigure);
            app.RandomNumberEditFieldLabel.Text = 'Random Number';
            app.RandomNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.RandomNumberEditField.Editable = 'off';
            app.GenerateNumberButton = uibutton(app.UIFigure, 'push');
            app.GenerateNumberButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNumberButtonPushed, true);
            app.GenerateNumberButton.Text = 'Generate Number';
            app.EnableSeedCheckBox = uicheckbox(app.UIFigure);
            app.EnableSeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSeedCheckBoxValueChanged, true);
            app.EnableSeedCheckBox.Text = 'Enable Seed';
            app.SeedEditFieldLabel = uilabel(app.UIFigure);
            app.SeedEditFieldLabel.Visible = 'off';
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeedEditField.Visible = 'off';
            app.Group13ProjectLabel = uilabel(app.UIFigure);
            app.Group13ProjectLabel.Visible = 'off';
            app.Group13ProjectLabel.Text = 'Group 13 - Project';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UniformRandomDistribution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end